classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TyresPanel                  matlab.ui.container.Panel
        Cos1CheckBox                matlab.ui.control.CheckBox
        SelectButton_2              matlab.ui.control.Button
        SelectButton                matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        RearTyresEditField          matlab.ui.control.EditField
        RearTyresEditFieldLabel     matlab.ui.control.Label
        FrontTyresEditField         matlab.ui.control.EditField
        FrontTyresEditFieldLabel    matlab.ui.control.Label
        AlfaRomeoGiuliaPanel        matlab.ui.container.Panel
        TestButtonGroup             matlab.ui.container.ButtonGroup
        ConstantRadiusButton        matlab.ui.control.ToggleButton
        RampspeedButton             matlab.ui.control.ToggleButton
        RampsteerButton             matlab.ui.control.ToggleButton
        VehicleModelButtonGroup     matlab.ui.container.ButtonGroup
        DoubletracknonlinearButton  matlab.ui.control.ToggleButton
        DoubletracklinearButton     matlab.ui.control.ToggleButton
        SingletracknonlinearButton  matlab.ui.control.ToggleButton
        SingletracklinearButton     matlab.ui.control.ToggleButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            currentFile = mfilename('fullpath');
            [pathstr, ~, ~] = fileparts(currentFile);
            folder = strcat(pathstr,'\file tir');
            [file,path] = uigetfile('*.tir','Select a file .tir',folder);
            app.FrontTyresEditField.Value = fullfile(path,file);
        end

        % Callback function
        function SelectButtonPushed(app, event)
            currentFile = mfilename('fullpath');
            [pathstr, ~, ~] = fileparts(currentFile);
            folder = strcat(pathstr,'\file tir');
            [file,path] = uigetfile('*.tir','Select a file .tir',folder);
            app.RearTyresEditField.Value = fullfile(path,file);
        end

        % Button pushed function: SelectButton
        function Model_select(app, event)
            app.Model_Button=3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlfaRomeoGiuliaPanel = uipanel(app.UIFigure);
            app.AlfaRomeoGiuliaPanel.Title = 'Alfa Romeo Giulia';
            app.VehicleModelButtonGroup = uibuttongroup(app.AlfaRomeoGiuliaPanel);
            app.VehicleModelButtonGroup.Title = 'Vehicle Model';
            app.SingletracklinearButton = uitogglebutton(app.VehicleModelButtonGroup);
            app.SingletracklinearButton.Text = 'Single track linear';
            app.SingletracklinearButton.Value = true;
            app.SingletracknonlinearButton = uitogglebutton(app.VehicleModelButtonGroup);
            app.SingletracknonlinearButton.Text = 'Single track non linear';
            app.DoubletracklinearButton = uitogglebutton(app.VehicleModelButtonGroup);
            app.DoubletracklinearButton.Text = 'Double track linear';
            app.DoubletracknonlinearButton = uitogglebutton(app.VehicleModelButtonGroup);
            app.DoubletracknonlinearButton.Text = 'Double track non linear';
            app.TestButtonGroup = uibuttongroup(app.AlfaRomeoGiuliaPanel);
            app.TestButtonGroup.Title = 'Test';
            app.RampsteerButton = uitogglebutton(app.TestButtonGroup);
            app.RampsteerButton.Text = 'Ramp steer';
            app.RampsteerButton.Value = true;
            app.RampspeedButton = uitogglebutton(app.TestButtonGroup);
            app.RampspeedButton.Text = 'Ramp speed';
            app.ConstantRadiusButton = uitogglebutton(app.TestButtonGroup);
            app.ConstantRadiusButton.Text = 'Constant Radius';
            app.TyresPanel = uipanel(app.UIFigure);
            app.TyresPanel.Title = 'Tyres';
            app.FrontTyresEditFieldLabel = uilabel(app.TyresPanel);
            app.FrontTyresEditFieldLabel.Text = 'Front Tyres';
            app.FrontTyresEditField = uieditfield(app.TyresPanel, 'text');
            app.RearTyresEditFieldLabel = uilabel(app.TyresPanel);
            app.RearTyresEditFieldLabel.Text = 'Rear Tyres';
            app.RearTyresEditField = uieditfield(app.TyresPanel, 'text');
            app.RunButton = uibutton(app.TyresPanel, 'push');
            app.RunButton.Text = 'Run';
            app.RunButton.Icon = fullfile(pathToMLAPP, 'Giulia.png');
            app.SelectButton = uibutton(app.TyresPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @Model_select, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.TyresPanel, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.Cos1CheckBox = uicheckbox(app.TyresPanel);
            app.Cos1CheckBox.Text = 'Cos(δ) ≠ 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end